classdef Planar3Links < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RobotAxes              matlab.ui.control.UIAxes
        r1SliderLabel          matlab.ui.control.Label
        r1Slider               matlab.ui.control.Slider
        r2SliderLabel          matlab.ui.control.Label
        r2Slider               matlab.ui.control.Slider
        r3SliderLabel          matlab.ui.control.Label
        r3Slider               matlab.ui.control.Slider
        targetxEditFieldLabel  matlab.ui.control.Label
        targetxEditField       matlab.ui.control.NumericEditField
        targetyEditFieldLabel  matlab.ui.control.Label
        targetyEditField       matlab.ui.control.NumericEditField
        RunButton              matlab.ui.control.Button
        StopButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        r1 % length of the 1st link
        r2 % length of the 2nd link
        r3 % length of the 3rd link
        th1 = 0.1; % angle of the 1st joint
        th2 = 0.1; % angle of the 2nd joint
        th3 = 0.1; % angle of the 3rd joint
        xy = [0.5;0.5]; % target xy position
        stop = true;
    end
    
    methods (Access = private)
        
        function showRobot(app)
            [x1,x2,x3] = app.forward();
            xy = cat(2,[0;0],x1,x2,x3);
            ws = app.workspace();
            plot(app.RobotAxes,xy(1,:),xy(2,:),'b'); 
            hold(app.RobotAxes,"on");
            scatter(app.RobotAxes,xy(1,:),xy(2,:),'r','filled');
            scatter(app.RobotAxes,ws(1,:),ws(2,:),2,'black','filled');
            hold(app.RobotAxes,"off");
            max_len = app.r1+app.r2+app.r3;
            axis(app.RobotAxes,[-max_len*1.1 max_len*1.1 -1 max_len*1.1]);
        end
        
        function [x1,x2,x3] = forward(app)
            r1 = app.r1;
            r2 = app.r2;
            r3 = app.r3;
            th1 = app.th1;
            th2 = app.th2;
            th3 = app.th3;
            x1 = [r1*cos(th1);
                  r1*sin(th1)];
            x2 = [r1*cos(th1)+r2*cos(th1+th2);
                  r1*sin(th1)+r2*sin(th1+th2)];
            x3 = [r1*cos(th1)+r2*cos(th1+th2)+r3*cos(th1+th2+th3);
                  r1*sin(th1)+r2*sin(th1+th2)+r3*sin(th1+th2+th3)];
        end
        
        function points = workspace(app)
            angles = 0:0.01:pi;
            numPoints = numel(angles);
            points = zeros(2,numPoints);
            r1 = app.r1;
            r2 = app.r2;
            r3 = app.r3;
            t2 = 0; t3 = 0;
            for i = 1:numPoints
                t1 = angles(i);
                x3 = [r1*cos(t1)+r2*cos(t1+t2)+r3*cos(t1+t2+t3);
                      r1*sin(t1)+r2*sin(t1+t2)+r3*sin(t1+t2+t3)];
                points(:,i) = x3;
            end
        end
        
        function J = jacobian(app)
            r1 = app.r1;
            r2 = app.r2;
            r3 = app.r3;
            th1 = app.th1;
            th2 = app.th2;
            th3 = app.th3;
            J = [-r1*sin(th1)-r2*sin(th1+th2)-r3*sin(th1+th2+th3) -r2*sin(th1+th2)-r3*sin(th1+th2+th3) -r3*sin(th1+th2+th3);
          r1*cos(th1)+r2*cos(th1+th2)+r3*cos(th1+th2+th3)  r2*cos(th1+th2)+r3*cos(th1+th2+th3)  r3*cos(th1+th2+th3)];
        end
        
        function J_ = pseudoInverse(app)
            J = app.jacobian();
            J_ = J'/(J*J');
        end
        
        function goToTarget(app)
            %% drive the robot arm to target xy location
            e = 0.05; % dead zone radius                      
            t = app.xy; % target tip position
            dt = 0.05; % time step size
            [x1,x2,x3] = app.forward();
            while(sqrt(dot(x3-t,x3-t))>e)
                if(app.stop)
                    break;
                end
                [x1,x2,x3] = app.forward();
                dxy = [1;1]*0.3;
                dxy = (t - x3);
                
                dth = app.pseudoInverse()*dxy;
                app.th1 = app.th1 + dth(1)*dt;
                app.th2 = app.th2 + dth(2)*dt;
                app.th3 = app.th3 + dth(3)*dt;
                app.showRobot();
                pause(dt*0.5);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.r1 = app.r1Slider.Value;
            app.r2 = app.r2Slider.Value;
            app.r3 = app.r3Slider.Value;
            app.showRobot();
        end

        % Value changed function: r1Slider
        function r1SliderValueChanged(app, event)
            value = app.r1Slider.Value;
            app.r1 = value;
            app.showRobot();
        end

        % Value changed function: r2Slider
        function r2SliderValueChanged(app, event)
            value = app.r2Slider.Value;
            app.r2 = value;
            app.showRobot();
        end

        % Value changed function: r3Slider
        function r3SliderValueChanged(app, event)
            value = app.r3Slider.Value;
            app.r3 = value;
            app.showRobot();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.stop = false;
            app.goToTarget();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = true;
        end

        % Value changed function: targetxEditField
        function targetxEditFieldValueChanged(app, event)
            value = app.targetxEditField.Value;
            app.xy(1) = value;
        end

        % Value changed function: targetyEditField
        function targetyEditFieldValueChanged(app, event)
            value = app.targetyEditField.Value;
            app.xy(2) = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RobotAxes = uiaxes(app.UIFigure);
            title(app.RobotAxes, 'Planar 3-Link Robot')
            xlabel(app.RobotAxes, 'X')
            ylabel(app.RobotAxes, 'Y')
            app.r1SliderLabel = uilabel(app.UIFigure);
            app.r1SliderLabel.Text = 'r1';
            app.r1Slider = uislider(app.UIFigure);
            app.r1Slider.Limits = [0.2 1.2];
            app.r1Slider.ValueChangedFcn = createCallbackFcn(app, @r1SliderValueChanged, true);
            app.r1Slider.Value = 0.5;
            app.r2SliderLabel = uilabel(app.UIFigure);
            app.r2SliderLabel.Text = 'r2';
            app.r2Slider = uislider(app.UIFigure);
            app.r2Slider.Limits = [0.2 1.2];
            app.r2Slider.ValueChangedFcn = createCallbackFcn(app, @r2SliderValueChanged, true);
            app.r2Slider.Value = 0.5;
            app.r3SliderLabel = uilabel(app.UIFigure);
            app.r3SliderLabel.Text = 'r3';
            app.r3Slider = uislider(app.UIFigure);
            app.r3Slider.Limits = [0.2 1.2];
            app.r3Slider.ValueChangedFcn = createCallbackFcn(app, @r3SliderValueChanged, true);
            app.r3Slider.Value = 0.5;
            app.targetxEditFieldLabel = uilabel(app.UIFigure);
            app.targetxEditFieldLabel.Text = 'target x';
            app.targetxEditField = uieditfield(app.UIFigure, 'numeric');
            app.targetxEditField.ValueChangedFcn = createCallbackFcn(app, @targetxEditFieldValueChanged, true);
            app.targetxEditField.Value = 0.5;
            app.targetyEditFieldLabel = uilabel(app.UIFigure);
            app.targetyEditFieldLabel.Text = 'target y';
            app.targetyEditField = uieditfield(app.UIFigure, 'numeric');
            app.targetyEditField.ValueChangedFcn = createCallbackFcn(app, @targetyEditFieldValueChanged, true);
            app.targetyEditField.Value = 0.5;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Planar3Links

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end